classdef appconESP32 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        focodeauscultacinLabel       matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        EstetoscopioelectrnicoLabel  matlab.ui.control.Label
        ColcateelestetoscopioenalgnLabel  matlab.ui.control.Label
        IngresalossiguientesdatosparacontinuarLabel  matlab.ui.control.Label
        BienvenidoalaAppLabel        matlab.ui.control.Label
        SexoDropDown                 matlab.ui.control.DropDown
        SexoDropDownLabel            matlab.ui.control.Label
        EdadEditField                matlab.ui.control.NumericEditField
        EdadEditFieldLabel           matlab.ui.control.Label
        NombreEditField              matlab.ui.control.EditField
        NombreEditFieldLabel         matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        GuardaraudioButton           matlab.ui.control.Button
        ObtenerPDFButton             matlab.ui.control.Button
        GeneraelPDFcontusdatosLabel  matlab.ui.control.Label
        EscucharButton               matlab.ui.control.Button
        MedirButton                  matlab.ui.control.Button
        ColcatelosaudfonosyescuchalasealLabel  matlab.ui.control.Label
        MidelasealLabel              matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        filt1; % Property to store the filtered signal
        audio_resampled; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NombreEditField
        function NombreEditFieldValueChanged(app, event)
            name = app.NombreEditField.Value;
        end

        % Value changed function: EdadEditField
        function EdadEditFieldValueChanged(app, event)
            age = app.EdadEditField.Value;
        end

        % Value changed function: SexoDropDown
        function SexoDropDownValueChanged(app, event)
            sexo = app.SexoDropDown.Value;
            
        end

        % Button pushed function: MedirButton
        function MedirButtonPushed(app, event)
            % Serial communication setup
            serialPort = "COM3"; % Replace with the actual port
            baudRate = 115200; 
            esp32 = serialport(serialPort, baudRate);
            
            % Data acquisition parameters
            numSamples = 5000; % Number of samples
            data = zeros(1, numSamples); % Preallocate for efficiency
            time = zeros(1, numSamples);
            
            % Reading loop
            disp('Reading data from ESP32...');
            tic; % Start timer
            for i = 1:numSamples
                if esp32.NumBytesAvailable > 0
                    rawData = readline(esp32); % Read data from ESP32
                    value = str2double(rawData); % Convert to number
                    if ~isnan(value) % Check for valid data
                        data(i) = value;
                        time(i) = toc;
                    else
                        warning('Invalid data received: %s', rawData);
                    end
                else
                    disp('No data available yet.');
                end
                pause(0.001); % Match ESP32 delay
            end
            
            % Close serial port
            clear esp32;
            
            data(1:783) = []; % Remove the first three elements
            time(1:783) = [];

            %Filtrar señal
            %tiempo=transpose(time);
            Fs=1000;                              %FRECUENCIA DE MUESTREO
            L=length(data); %número de datos
            t=L/Fs; % número de datos/frecuencia de muestreo
            x=data; %renombrar al voltaje
            % FILTRO DIGITAL PASA BANDAS
            filt=bandpass(x,[20,75],Fs);
            %graficar señales y sus espectros de frecuencia
            app.filt1=normalize(filt,"range",[-1,1]);

            % GRAFICAR LA SEÑAL FINAL YA FILTRADA
            tiempo_cali=0:0.001:4.216;
            figure1 = figure('Visible','off');
            plot(app.UIAxes,tiempo_cali,app.filt1); grid on;%señal normal
            plot(time,app.filt1); title("Fonocardiograma"); xlabel("Tiempo [s]"); ylabel("Voltaje [V]");grid on;
            %xticks(0:0.2:4.4); yticks(-1:0.2:1);
            % Define a relative path
            filePath = 'C:\Users\Poh\Documents\PROYECTO FINAL IB\fonocardiograma.png'; % Assuming "Figures" folder exists in the current directory
           
            % Save the figure
            saveas(gcf, filePath);
        end

        % Button pushed function: EscucharButton
        function EscucharButtonPushed(app, event)
            % PRUEBAS CON RESAMPLE
            target_length = 185220;
            app.audio_resampled = resample(app.filt1, target_length, length(app.filt1));
            app.audio_resampled=normalize(app.audio_resampled,"range",[-1,1]);
            %figure
            %plot(audio_resampled)
            % GENERAR SONIDO CON SEÑAL RESAMPLE
            sound(app.audio_resampled,44100)
            %audiowrite("prueba_corazon1.wav",audio_resampled,44100)
        end

        % Button pushed function: ObtenerPDFButton
        function ObtenerPDFButtonPushed(app, event)
            name = app.NombreEditField.Value; % Access the field's value directly
            age = app.EdadEditField.Value;   % Access the age field value
            sex = app.SexoDropDown.Value;   % Access the age field value
            disp(name); % Display the name
            disp(age);  % Display the age
            import mlreportgen.dom.*;

            % Create a new PDF document
            doc = Document('example_report', 'pdf');
            
            % Add a title
            title = Paragraph('Reporte de Fonocardiograma');
            title.Bold = true;
            title.FontSize = '18pt';
            append(doc, title);
            
            % Add some text
            text = Paragraph('Nombre: ');
            % Convert the variable to text and append it to the paragraph
            textElement = Text(num2str(name));
            text.FontSize = '16pt';
            append(text, textElement);
            
            
            % Append the paragraph to the document
            append(doc, text);
            
             % Add some text
            text2 = Paragraph('Edad: ');
            % Convert the variable to text and append it to the paragraph
            text2Element = Text(num2str(age));
            text2.FontSize = '16pt';
            append(text2, text2Element);
            
            
            % Append the paragraph to the document
            append(doc, text2);

             % Add some text
            text3 = Paragraph('Sexo: ');
            % Convert the variable to text and append it to the paragraph
            text3Element = Text(num2str(sex));
            text3.FontSize = '16pt';
            append(text3, text3Element);
            
            
            % Append the paragraph to the document
            append(doc, text3);

            
            % Add an image (optional)
            imagePath = 'C:\Users\Poh\Documents\PROYECTO FINAL IB\fonocardiograma.png';
            image = Image(imagePath); % Replace with your actual image path
            % Set the size of the image (e.g., 3 inches wide and 2 inches tall)
            image.Width = '7in';
            image.Height = '5in';
            % Append the paragraph to the document
            append(doc, image);


            % Finalize and close the document
            close(doc);
            
            % Open the PDF
            rptview(doc.OutputPath);

        end

        % Button pushed function: GuardaraudioButton
        function GuardaraudioButtonPushed(app, event)
            audiowrite('C:\Users\Poh\Documents\PROYECTO FINAL IB\fonocardiograma.wav',app.audio_resampled,44100);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {231, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.NombreEditFieldLabel = uilabel(app.LeftPanel);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.LeftPanel, 'text');
            app.NombreEditField.ValueChangedFcn = createCallbackFcn(app, @NombreEditFieldValueChanged, true);
            app.EdadEditFieldLabel = uilabel(app.LeftPanel);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.EdadEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EdadEditField.ValueChangedFcn = createCallbackFcn(app, @EdadEditFieldValueChanged, true);
            app.SexoDropDownLabel = uilabel(app.LeftPanel);
            app.SexoDropDownLabel.Text = 'Sexo';
            app.SexoDropDown = uidropdown(app.LeftPanel);
            app.SexoDropDown.Items = {'Mujer', 'Hombre'};
            app.SexoDropDown.ValueChangedFcn = createCallbackFcn(app, @SexoDropDownValueChanged, true);
            app.SexoDropDown.Value = 'Mujer';
            app.BienvenidoalaAppLabel = uilabel(app.LeftPanel);
            app.BienvenidoalaAppLabel.Text = 'Bienvenido a la App ';
            app.IngresalossiguientesdatosparacontinuarLabel = uilabel(app.LeftPanel);
            app.IngresalossiguientesdatosparacontinuarLabel.Text = {'1. Ingresa los siguientes datos para '; 'continuar'};
            app.ColcateelestetoscopioenalgnLabel = uilabel(app.LeftPanel);
            app.ColcateelestetoscopioenalgnLabel.Text = '2. Colócate el estetoscopio en algún';
            app.EstetoscopioelectrnicoLabel = uilabel(app.LeftPanel);
            app.EstetoscopioelectrnicoLabel.Text = 'Estetoscopio electrónico';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'auscu.jpg');
            app.focodeauscultacinLabel = uilabel(app.LeftPanel);
            app.focodeauscultacinLabel.Text = 'foco de auscultación';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Fonocardiograma')
            xlabel(app.UIAxes, 'Tiempo [s]')
            ylabel(app.UIAxes, 'Amplitud [V]')
            zlabel(app.UIAxes, 'Z')
            app.MidelasealLabel = uilabel(app.RightPanel);
            app.MidelasealLabel.Text = '3. Mide la señal';
            app.ColcatelosaudfonosyescuchalasealLabel = uilabel(app.RightPanel);
            app.ColcatelosaudfonosyescuchalasealLabel.Text = {'4. Colócate los audífonos y escucha'; '    la señal'};
            app.MedirButton = uibutton(app.RightPanel, 'push');
            app.MedirButton.ButtonPushedFcn = createCallbackFcn(app, @MedirButtonPushed, true);
            app.MedirButton.Text = 'Medir';
            app.EscucharButton = uibutton(app.RightPanel, 'push');
            app.EscucharButton.ButtonPushedFcn = createCallbackFcn(app, @EscucharButtonPushed, true);
            app.EscucharButton.Text = 'Escuchar';
            app.GeneraelPDFcontusdatosLabel = uilabel(app.RightPanel);
            app.GeneraelPDFcontusdatosLabel.Text = '5. ¡Genera el PDF con tus datos!';
            app.ObtenerPDFButton = uibutton(app.RightPanel, 'push');
            app.ObtenerPDFButton.ButtonPushedFcn = createCallbackFcn(app, @ObtenerPDFButtonPushed, true);
            app.ObtenerPDFButton.Text = 'Obtener PDF';
            app.GuardaraudioButton = uibutton(app.RightPanel, 'push');
            app.GuardaraudioButton.ButtonPushedFcn = createCallbackFcn(app, @GuardaraudioButtonPushed, true);
            app.GuardaraudioButton.Text = 'Guardar audio';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appconESP32

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end